classdef eToolsFilterDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimTab                          matlab.ui.container.Tab
        SimulationButton                matlab.ui.control.Button
        TranPanel                       matlab.ui.container.Panel
        EditField_fs                    matlab.ui.control.EditField
        fsLabel                         matlab.ui.control.Label
        EditField_tstop                 matlab.ui.control.EditField
        tstopLabel                      matlab.ui.control.Label
        ACPanel                         matlab.ui.container.Panel
        EditField_fstop                 matlab.ui.control.EditField
        fstopLabel                      matlab.ui.control.Label
        EditField_fstart                matlab.ui.control.EditField
        fstartLabel                     matlab.ui.control.Label
        SynthesisButton                 matlab.ui.control.Button
        FilterParametersPanel           matlab.ui.container.Panel
        ENCheckBox_TeeEn                matlab.ui.control.CheckBox
        ENCheckBox_AstopEn              matlab.ui.control.CheckBox
        EditField_Zout                  matlab.ui.control.EditField
        ZoutLabel                       matlab.ui.control.Label
        EditField_Zin                   matlab.ui.control.EditField
        ZinLabel                        matlab.ui.control.Label
        EditField_Astop                 matlab.ui.control.EditField
        AstopLabel                      matlab.ui.control.Label
        EditField_Apass                 matlab.ui.control.EditField
        ApassLabel                      matlab.ui.control.Label
        EditField_F2                    matlab.ui.control.EditField
        F2BWLabel                       matlab.ui.control.Label
        EditField_F1                    matlab.ui.control.EditField
        F1CFEditFieldLabel              matlab.ui.control.Label
        ButterworthDropDownOrder        matlab.ui.control.DropDown
        OrderLabel                      matlab.ui.control.Label
        ButterworthDropDownFilterType2  matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        ButterworthDropDownFilterType1  matlab.ui.control.DropDown
        TypeLabel_2                     matlab.ui.control.Label
        TabGroupPlot                    matlab.ui.container.TabGroup
        TabMag                          matlab.ui.container.Tab
        UIAxesMag                       matlab.ui.control.UIAxes
        TabPhase                        matlab.ui.container.Tab
        UIAxesPhase                     matlab.ui.control.UIAxes
        TabTran                         matlab.ui.container.Tab
        UIAxesTran                      matlab.ui.control.UIAxes
        UIAxesSch                       matlab.ui.control.UIAxes
        GuideTab                        matlab.ui.container.Tab
        OptTab                          matlab.ui.container.Tab
        ExportTab                       matlab.ui.container.Tab
        SettingTab                      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.EditField_Astop, 'Enable', 'off');
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            fType = app.ButterworthDropDownFilterType1.Value;
            n     = str2double(app.ButterworthDropDownOrder.Value);
            Rs    = Suffix2Data(app.EditField_Zin.Value);
            Rl    = Suffix2Data(app.EditField_Zout.Value);
            fp    = Suffix2Data(app.EditField_F1.Value);
            fs    = Suffix2Data(app.EditField_F2.Value);
            Ap    = Suffix2Data(app.EditField_Apass.Value);
            As    = Suffix2Data(app.EditField_Astop.Value);
            TeeEn = (app.ENCheckBox_TeeEn.Value);
            [strNetlist] = funSynthesisButterworthFilter(fType, TeeEn, n, Rs, Rl, fp, fs, Ap, As);
            % split netlist
            [iType, Value, cellNode1, CellNode2, cellName] = funSimNetlist2Array(strNetlist);
            % netlist standard
            [node1, node2] = funSimNetlistRenode(cellNode1, CellNode2);
            % netlist analysis
            [maxNode, nL, nI, nV, nR0] = funSimNetlistAna(iType, Value, node1, node2);
            % 标记给定器件的非GND节点用于获取结果位置
            strDevice = 'RL'; % 显示结果的器件
            [retNode] = funGetDeviceNode(cellName, node1, node2, strDevice);
            % generate matrix, (MM*s+MN)*MX = MV
            [MM, MN, MV, MX] = funSimNetlist2Matrix(iType, Value, node1, node2, maxNode, nL, nI, nV, nR0, cellName);
            f0     = fp;
            w0     = 2*pi*f0;
            axPlot = app.UIAxesSch;
            [img] = funGenSchematic2(axPlot, iType, Value, cellNode1, CellNode2, cellName, w0);
            ylimValue = ylim(axPlot);
            ylim(axPlot, [-ceil(-ylimValue(1)), ceil(ylimValue(2))]);
            %% AC sim
            f0 = Suffix2Data(app.EditField_fstart.Value);
            f1 = Suffix2Data(app.EditField_fstop.Value);
            % f0 = 1e4;
            % f1 = 1e6;
            N       = 500;
            axMag   = app.UIAxesMag;
            axPhase = app.UIAxesPhase;
            funACSim(axMag, axPhase, f0, f1, N, cellName, MX, MM, MN, MV, Value, node1, node2);
            %% Tran Sim
            Tmax   = Suffix2Data(app.EditField_tstop.Value);
            f1     = Suffix2Data(app.EditField_fs.Value);
            Nmax   = 100000; % 
            axTran = app.UIAxesTran;
            funTranSim(axTran, f1, Tmax, Nmax, MX, MM, MN, MV, retNode, cellName, Value)
        end

        % Value changed function: ENCheckBox_AstopEn
        function ENCheckBox_AstopEnValueChanged(app, event)
            value = app.ENCheckBox_AstopEn.Value;
            if value
                set(app.EditField_Astop, 'Enable', 'on');
                set(app.ButterworthDropDownOrder, 'Enable', 'off');
            else
                set(app.EditField_Astop, 'Enable', 'off');
                set(app.ButterworthDropDownOrder, 'Enable', 'on');
            end
        end

        % Value changed function: ENCheckBox_TeeEn
        function ENCheckBox_TeeEnValueChanged(app, event)
            value = app.ENCheckBox_TeeEn.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimTab = uitab(app.TabGroup);
            app.SimTab.Title = 'Sim';
            app.UIAxesSch = uiaxes(app.SimTab);
            xlabel(app.UIAxesSch, 'X')
            ylabel(app.UIAxesSch, 'Y')
            zlabel(app.UIAxesSch, 'Z')
            app.TabGroupPlot = uitabgroup(app.SimTab);
            app.TabMag = uitab(app.TabGroupPlot);
            app.TabMag.Title = 'Mag';
            app.UIAxesMag = uiaxes(app.TabMag);
            title(app.UIAxesMag, 'Title')
            xlabel(app.UIAxesMag, 'X')
            ylabel(app.UIAxesMag, 'Y')
            zlabel(app.UIAxesMag, 'Z')
            app.TabPhase = uitab(app.TabGroupPlot);
            app.TabPhase.Title = 'Phase';
            app.UIAxesPhase = uiaxes(app.TabPhase);
            title(app.UIAxesPhase, 'Title')
            xlabel(app.UIAxesPhase, 'X')
            ylabel(app.UIAxesPhase, 'Y')
            zlabel(app.UIAxesPhase, 'Z')
            app.TabTran = uitab(app.TabGroupPlot);
            app.TabTran.Title = 'Tran';
            app.UIAxesTran = uiaxes(app.TabTran);
            title(app.UIAxesTran, 'Title')
            xlabel(app.UIAxesTran, 'X')
            ylabel(app.UIAxesTran, 'Y')
            zlabel(app.UIAxesTran, 'Z')
            app.FilterParametersPanel = uipanel(app.SimTab);
            app.FilterParametersPanel.Title = 'FilterParameters';
            app.TypeLabel_2 = uilabel(app.FilterParametersPanel);
            app.TypeLabel_2.Text = 'Type';
            app.ButterworthDropDownFilterType1 = uidropdown(app.FilterParametersPanel);
            app.ButterworthDropDownFilterType1.Items = {'LPF', 'HPF', 'BPF', 'BRF', 'APF'};
            app.ButterworthDropDownFilterType1.Value = 'LPF';
            app.TypeLabel = uilabel(app.FilterParametersPanel);
            app.TypeLabel.Text = 'Type';
            app.ButterworthDropDownFilterType2 = uidropdown(app.FilterParametersPanel);
            app.ButterworthDropDownFilterType2.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II', 'Elliptic', 'Bessel', 'Gaussian', 'LinearPhase'};
            app.ButterworthDropDownFilterType2.Value = 'Butterworth';
            app.OrderLabel = uilabel(app.FilterParametersPanel);
            app.OrderLabel.Text = 'Order';
            app.ButterworthDropDownOrder = uidropdown(app.FilterParametersPanel);
            app.ButterworthDropDownOrder.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.ButterworthDropDownOrder.Value = '5';
            app.F1CFEditFieldLabel = uilabel(app.FilterParametersPanel);
            app.F1CFEditFieldLabel.Text = 'F1/CF';
            app.EditField_F1 = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_F1.Value = '1G';
            app.F2BWLabel = uilabel(app.FilterParametersPanel);
            app.F2BWLabel.Text = 'F2/BW';
            app.EditField_F2 = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_F2.Value = '2G';
            app.ApassLabel = uilabel(app.FilterParametersPanel);
            app.ApassLabel.Text = 'Apass';
            app.EditField_Apass = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_Apass.Value = '-3';
            app.AstopLabel = uilabel(app.FilterParametersPanel);
            app.AstopLabel.Text = 'Astop';
            app.EditField_Astop = uieditfield(app.FilterParametersPanel, 'text');
            app.ZinLabel = uilabel(app.FilterParametersPanel);
            app.ZinLabel.Text = 'Zin';
            app.EditField_Zin = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_Zin.Value = '50';
            app.ZoutLabel = uilabel(app.FilterParametersPanel);
            app.ZoutLabel.Text = 'Zout';
            app.EditField_Zout = uieditfield(app.FilterParametersPanel, 'text');
            app.EditField_Zout.Value = '50';
            app.ENCheckBox_AstopEn = uicheckbox(app.FilterParametersPanel);
            app.ENCheckBox_AstopEn.ValueChangedFcn = createCallbackFcn(app, @ENCheckBox_AstopEnValueChanged, true);
            app.ENCheckBox_AstopEn.Text = 'EN';
            app.ENCheckBox_TeeEn = uicheckbox(app.FilterParametersPanel);
            app.ENCheckBox_TeeEn.ValueChangedFcn = createCallbackFcn(app, @ENCheckBox_TeeEnValueChanged, true);
            app.ENCheckBox_TeeEn.Text = 'TEN';
            app.SynthesisButton = uibutton(app.SimTab, 'push');
            app.SynthesisButton.Text = 'Synthesis';
            app.ACPanel = uipanel(app.SimTab);
            app.ACPanel.Title = 'AC';
            app.fstartLabel = uilabel(app.ACPanel);
            app.fstartLabel.Text = 'fstart';
            app.EditField_fstart = uieditfield(app.ACPanel, 'text');
            app.EditField_fstart.Value = '1M';
            app.fstopLabel = uilabel(app.ACPanel);
            app.fstopLabel.Text = 'fstop';
            app.EditField_fstop = uieditfield(app.ACPanel, 'text');
            app.EditField_fstop.Value = '10G';
            app.TranPanel = uipanel(app.SimTab);
            app.TranPanel.Title = 'Tran';
            app.tstopLabel = uilabel(app.TranPanel);
            app.tstopLabel.Text = 'tstop';
            app.EditField_tstop = uieditfield(app.TranPanel, 'text');
            app.EditField_tstop.Value = '25n';
            app.fsLabel = uilabel(app.TranPanel);
            app.fsLabel.Text = 'fs';
            app.EditField_fs = uieditfield(app.TranPanel, 'text');
            app.EditField_fs.Value = '1G';
            app.SimulationButton = uibutton(app.SimTab, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.GuideTab = uitab(app.TabGroup);
            app.GuideTab.Title = 'Guide';
            app.OptTab = uitab(app.TabGroup);
            app.OptTab.Title = 'Opt';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eToolsFilterDesignApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end